classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UIAxes                      matlab.ui.control.UIAxes
        XLabel                      matlab.ui.control.Label
        gpx1                        matlab.ui.control.NumericEditField
        YEditFieldLabel             matlab.ui.control.Label
        gpy1                        matlab.ui.control.NumericEditField
        status                      matlab.ui.control.EditField
        Panel                       matlab.ui.container.Panel
        play                        matlab.ui.control.Button
        ChooseCSVFileButton         matlab.ui.control.Button
        WorkCalculatorPanel         matlab.ui.container.Panel
        YourWeightkgEditFieldLabel  matlab.ui.control.Label
        weight                      matlab.ui.control.NumericEditField
        calculate                   matlab.ui.control.Button
        WorkdoneEditFieldLabel      matlab.ui.control.Label
        workdone                    matlab.ui.control.NumericEditField
        UITable                     matlab.ui.control.Table
    end

    
    properties (Access = private)
        map = 0;% Description
        data = 0;
    end
    
    methods (Access = private)
        
        function plot(app,x,y)
            imshow('CampusMap.png', 'parent', app.UIAxes);
            app.UIAxes.YDir = 'reverse';   

            hold (app.UIAxes, 'on');
            x = interp(x,6);
            y = interp(y,6);
            c = gradient(y);
            
            scatter(app.UIAxes, x, y, 8, c, 'filled');
            
            if(x(end)>x(1) && y(1)>y(end))
                app.UIAxes.XLim = [(x(1)-100), x(end)+100];
                app.UIAxes.YLim = [(y(end)-100), y(1)+100];
            elseif(x(1)>x(end) && y(end)>y(1))
                app.UIAxes.XLim = [(x(end)-100), x(1)+100];
                app.UIAxes.YLim = [(y(1)-100), y(end)+100]; 
            elseif(x(end)>x(1) && y(end)>y(1))
                app.UIAxes.XLim = [(x(1)-100), x(end)+100];
                app.UIAxes.YLim = [(y(1)-100), y(end)+100];
            else
                app.UIAxes.XLim = [(x(end)-100), x(1)+100];
                app.UIAxes.YLim = [(y(end)-100), y(1)+100];                
            end
        end
        
        function animate(app,x,y)
        
            imshow('CampusMap.png', 'parent', app.UIAxes);
            app.UIAxes.YDir = 'reverse';   

            hold (app.UIAxes, 'on');
            x = interp(x,6);
            y = interp(y,6);
            c = gradient(y);
            
            app.UIAxes.XLim = [(x(1)-100), x(end)+400];
            app.UIAxes.YLim = [(y(end)-100), y(1)+100];
            
            for i = 1:length(x)
                scatter(app.UIAxes, x(i), y(i), 8, c(i), 'filled');    
                pause(abs(1/(c.*10000))*4);
            end           


                 app.UIAxes.XLim = [(x(1)-600), x(end)+600];
                 app.UIAxes.YLim = [(y(1)-600), y(end)+600];  
        end
        
        function work = calculateWork(app)
            aveSpeed = mean(app.data.speed);
            mass = app.weight.Value;
            g = 9.81;
            time = 0;
            work = 0;
            
            if(iscell(app.data.Timestamp))
                app.data.Timestamp = datetime(app.data.Timestamp, 'InputFormat', 'mm:ss');
                app.data.Timestamp.Format = 'mm:ss';
                app.data.Timestamp = datenum(app.data.Timestamp);
                time = sum(app.data.Timestamp);
            else
                app.status.Value = "Wrong file format";
            end 
            
            distance = aveSpeed*time;
            force = mass*g;
            
            if(distance > 0)
                 work = distance*force;
            else
                app.status.Value = "No distance"
            end 
            
            if(work>0)
                app.status.Value = "Nice!";
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disableDefaultInteractivity(app.UIAxes);
            imshow('CampusMap.png', 'parent', app.UIAxes);
        end

        % Button pushed function: ChooseCSVFileButton
        function ChooseCSVFileButtonPushed(app, event)
            disableDefaultInteractivity(app.UIAxes);
            [file,~] = uigetfile('*.csv');
             if isequal(file, 0)
                app.status.Value = "No CSV file detected!";
                drawnow;
                figure(app.UIFigure)               
               return;
             else
                app.data = readtable(file);
                drawnow;
                figure(app.UIFigure)
                if(table2array(app.data(3,2)) > 0)
                   latitude = table2array(app.data(:,2)); longitude = table2array(app.data(:,3));
                else
                   latitude = table2array(app.data(:,3)); longitude = table2array(app.data(:,2));
                end 
                
                app.map = mapping;           
                app.map.gpy2Pixel(latitude);
                app.map.gpx2Pixel(longitude);

                indx = app.map.x > 1800;
                indy = app.map.y > 1800;
                
                if(any(indx) || any(indy))
                    app.map.x(indx) = [];
                    app.map.x(indy) = [];
                    app.map.y(indx) = [];
                    app.map.y(indy) = [];
                end
                
                set(app.UITable, 'data', app.data);
               
                app.plot(app.map.x,app.map.y);
             end
        end

        % Value changed function: gpy1
        function gpy1ValueChanged(app, event)
            value = app.gpy1.Value;
            cla(app.UIAxes, 'reset');
            if(app.map == 0)
                app.map = mapping;
                app.map.gpx2Pixel(app.gpx1.Value);
                app.map.gpy2Pixel(value);
                app.plot(app.map.x, app.map.y);
            else
                app.map.gpx2Pixel(app.gpx1.Value);
                app.map.gpy2Pixel(value);
                app.plot(app.map.x, app.map.y);
            end

        end

        % Button pushed function: play
        function playButtonPushed(app, event)
            if(app.map == 0)
                app.status.Value = "Please choose csv app.data!";
                imshow('meme.jpeg','parent',app.UIAxes)
            else
                   app.animate(app.map.x, app.map.y);
            end
        end

        % Button pushed function: calculate
        function calculateButtonPushed(app, event)
            app.workdone.Value = app.calculateWork();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'Longitude')
            ylabel(app.UIAxes, 'Latitude')
            app.UIAxes.XTick = [];
            app.XLabel = uilabel(app.UIFigure);
            app.XLabel.Text = 'X:';
            app.gpx1 = uieditfield(app.UIFigure, 'numeric');
            app.gpx1.ValueDisplayFormat = '%11.9g';
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y:';
            app.gpy1 = uieditfield(app.UIFigure, 'numeric');
            app.gpy1.ValueDisplayFormat = '%11.9g';
            app.gpy1.ValueChangedFcn = createCallbackFcn(app, @gpy1ValueChanged, true);
            app.status = uieditfield(app.UIFigure, 'text');
            app.status.Editable = 'off';
            app.status.Value = 'Status bar';
            app.Panel = uipanel(app.UIFigure);
            app.play = uibutton(app.Panel, 'push');
            app.play.ButtonPushedFcn = createCallbackFcn(app, @playButtonPushed, true);
            app.play.Text = 'Animate';
            app.ChooseCSVFileButton = uibutton(app.Panel, 'push');
            app.ChooseCSVFileButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseCSVFileButtonPushed, true);
            app.ChooseCSVFileButton.Text = 'Choose CSV File';
            app.WorkCalculatorPanel = uipanel(app.UIFigure);
            app.WorkCalculatorPanel.Title = 'Work Calculator';
            app.YourWeightkgEditFieldLabel = uilabel(app.WorkCalculatorPanel);
            app.YourWeightkgEditFieldLabel.Text = 'Your Weight (kg)';
            app.weight = uieditfield(app.WorkCalculatorPanel, 'numeric');
            app.calculate = uibutton(app.WorkCalculatorPanel, 'push');
            app.calculate.ButtonPushedFcn = createCallbackFcn(app, @calculateButtonPushed, true);
            app.calculate.Text = 'Calculate';
            app.WorkdoneEditFieldLabel = uilabel(app.WorkCalculatorPanel);
            app.WorkdoneEditFieldLabel.Text = 'Work done(J):';
            app.workdone = uieditfield(app.WorkCalculatorPanel, 'numeric');
            app.workdone.Editable = 'off';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Time(mm:ss)'; 'Latitude'; 'Longitude'; 'Speed(m/s)'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end